classdef GMGDataPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMGDataPickerUIFigure  matlab.ui.Figure
        ClearPicksButton       matlab.ui.control.Button
        XMin                   matlab.ui.control.NumericEditField
        XMax                   matlab.ui.control.NumericEditField
        YMin                   matlab.ui.control.NumericEditField
        YMax                   matlab.ui.control.NumericEditField
        SetLimitsButton        matlab.ui.control.Button
        CMaxEditField          matlab.ui.control.NumericEditField
        CMaxEditFieldLabel     matlab.ui.control.Label
        CMinEditField          matlab.ui.control.NumericEditField
        CMinEditFieldLabel     matlab.ui.control.Label
        ColormapDropDown       matlab.ui.control.DropDown
        ColormapDropDownLabel  matlab.ui.control.Label
        PointdataLabel         matlab.ui.control.Label
        SwitchPointData        matlab.ui.control.Switch
        SurfacedataLabel       matlab.ui.control.Label
        SwitchSurfaceData      matlab.ui.control.Switch
        VolumedataLabel        matlab.ui.control.Label
        SwitchVolumeData       matlab.ui.control.Switch
        VolumeDataDropDown     matlab.ui.control.DropDown
        ClearDataButton        matlab.ui.control.Button
        StopPickingButton      matlab.ui.control.Button
        LoadDataButton         matlab.ui.control.Button
        SavePicksButton        matlab.ui.control.Button
        LoadPicksButton        matlab.ui.control.Button
        StartPickingButton     matlab.ui.control.Button
        GeophysicalModelGeneratorDataPickerLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a structure to hold all the data for the profiles
            app.GMGDataPickerUIFigure.UserData = [];
        end

        % Button pushed function: LoadDataButton
        function OpendatasetMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                % disp('User selected Cancel');
                % nothing happens now
            else
                % load the file into the user data structure
                app.GMGDataPickerUIFigure.UserData = load([path,file]);
                VolData = app.GMGDataPickerUIFigure.UserData.ProfileData.VolData;

                X = VolData.fields.x_profile;
                Y = VolData.depth.val;
                app.GMGDataPickerUIFigure.UserData.FieldData = rmfield(VolData.fields,'x_profile');
                FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
                % set up the dropdown menus for volume data
                VolFieldCell = fields(app.GMGDataPickerUIFigure.UserData.FieldData);
                app.VolumeDataDropDown.Items   = VolFieldCell;

                % plot the first volume dataset in all tabs so that we have
                % something to see
                app.GMGDataPickerUIFigure.UserData.VolHandle(1) = pcolor(app.UIAxes,X,Y,FieldData.(app.VolumeDataDropDown.Items{1}));
                axis(app.UIAxes,'equal')
                axis(app.UIAxes,'tight')
                shading(app.UIAxes,'interp')
                colorbar(app.UIAxes,"eastoutside")
                colormap(app.UIAxes,"turbo")
                hold(app.UIAxes,'on')
                app.SwitchVolumeData.Value = 'On';

                % plot the surface data --> all of it                
                for isurf = 1:length(app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData)
                    X = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData{isurf}.fields.x_profile;
                    Y = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData{isurf}.depth.val;
                    
                    app.SwitchSurfaceData.Value = 'On';
                    app.GMGDataPickerUIFigure.UserData.SurfHandle(isurf) = plot(app.UIAxes,X,Y,'k-','LineWidth',2);
                end

                % plot the point data --> all of it
                for ipoint = 1:length(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData)
                    X = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.fields.x_profile; % diatance along profile is already computed in km!
                    Y = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.depth.val;

                    app.SwitchPointData.Value = 'On';
                    app.GMGDataPickerUIFigure.UserData.PointHandle(ipoint) = plot(app.UIAxes,X,Y,'ko','MarkerSize',8,'MarkerFaceColor','r');
                end

                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            OpendatasetMenuSelected(app, event)
        end

        % Value changed function: VolumeDataDropDown
        function VolumeDataDropDownValueChanged(app, event)
            value = app.VolumeDataDropDown.Value;
            FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
            app.GMGDataPickerUIFigure.UserData.VolHandle(1).CData = FieldData.(value);
        end

        % Value changed function: CMinEditField
        function CMinEditFieldValueChanged(app, event)
            value = app.CMinEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[value max(clim(app.UIAxes))])
            else
                clim(app.UIAxes,[value max(clim(app.UIAxes))])
            end

        end

        % Value changed function: CMaxEditField
        function CMaxEditFieldValueChanged(app, event)
            value = app.CMaxEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[min(clim(app.UIAxes)) value])
            else
                clim(app.UIAxes,[min(clim(app.UIAxes)) value])
            end
        end

        % Button pushed function: SetLimitsButton
        function SetLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes.YLim = [app.YMin.Value app.YMax.Value];
        end

        % Value changed function: SwitchVolumeData
        function SwitchVolumeDataValueChanged(app, event)
            value = app.SwitchVolumeData.Value;
            if strcmp(value,'On')
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = true;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = false;
            end
        end

        % Button pushed function: StartPickingButton
        function StartPickingButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.GMGDataPickerUIFigure.HandleVisibility;        % Current status
            app.GMGDataPickerUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.GMGDataPickerUIFigure)       % Make fig current
    
            % allocate vector for data
            if isfield(app.GMGDataPickerUIFigure.UserData,'PickedX')
                if isempty(app.GMGDataPickerUIFigure.UserData.PickedX)
                    app.GMGDataPickerUIFigure.UserData.PickedX = NaN(100,1);
                    app.GMGDataPickerUIFigure.UserData.PickedZ = NaN(100,1);
                    iadd = 0;
                else
                    iadd = sum(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
                end
            else
               app.GMGDataPickerUIFigure.UserData.PickedX = NaN(100,1);
               app.GMGDataPickerUIFigure.UserData.PickedZ = NaN(100,1);
               iadd = 0;
            end

            app.GMGDataPickerUIFigure.UserData.KeepPicking = true;
            while app.GMGDataPickerUIFigure.UserData.KeepPicking
                iadd = iadd+1;
                % use ginputuiax to create a new profile
                [x1,y1] = ginput(1);
                app.GMGDataPickerUIFigure.UserData.PickedX(iadd) = x1;
                app.GMGDataPickerUIFigure.UserData.PickedZ(iadd) = y1;

                if iadd==1
                    app.GMGDataPickerUIFigure.UserData.hPick = plot3(app.UIAxes,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,110e3*ones(size(app.GMGDataPickerUIFigure.UserData.PickedX)),'rv','MarkerFaceColor','w','MarkerEdgeColor','k');
                else
                    app.GMGDataPickerUIFigure.UserData.hPick.XData = app.GMGDataPickerUIFigure.UserData.PickedX;
                    app.GMGDataPickerUIFigure.UserData.hPick.YData = app.GMGDataPickerUIFigure.UserData.PickedZ;
                end
                app.GMGDataPickerUIFigure.UserData.PickedX(iadd) = x1;
                app.GMGDataPickerUIFigure.UserData.PickedZ(iadd) = y1;
                pause(0.5)
            end
            
            % remove last point as this is the stop button
            app.GMGDataPickerUIFigure.UserData.PickedX(iadd) = NaN;
            app.GMGDataPickerUIFigure.UserData.PickedZ(iadd) = NaN;
            app.GMGDataPickerUIFigure.UserData.hPick.XData = app.GMGDataPickerUIFigure.UserData.PickedX;
            app.GMGDataPickerUIFigure.UserData.hPick.YData = app.GMGDataPickerUIFigure.UserData.PickedZ;

            app.GMGDataPickerUIFigure.HandleVisibility = fhv;        % return original state

        end

        % Button pushed function: StopPickingButton
        function StopPickingButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.KeepPicking = false;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % clear all axes and User data
            app.GMGDataPickerUIFigure.UserData = [];
            cla(app.UIAxes)
        end

        % Button pushed function: SavePicksButton
        function SavePicksButtonPushed(app, event)
            % open save file dialogue
            [FILE,PATH] = uiputfile('*.txt');
            X = app.GMGDataPickerUIFigure.UserData.PickedX(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            Z = app.GMGDataPickerUIFigure.UserData.PickedZ(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            if FILE~=0
                % save the picked data data as ascii
                T = table(X,Z);
                writetable(T,[PATH,FILE])
            end
        end

        % Button pushed function: ClearPicksButton
        function ClearPicksButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.PickedX = [];
            app.GMGDataPickerUIFigure.UserData.PickedZ = [];
            app.GMGDataPickerUIFigure.UserData.hPick.XData = [];
            app.GMGDataPickerUIFigure.UserData.hPick.YData = [];
        end

        % Button pushed function: LoadPicksButton
        function LoadPicksButtonPushed(app, event)
            % load profile file -> should be mat or text file
            [FILE,PATH] = uigetfile("*.txt");

            if FILE~=0
                T = readtable([PATH,FILE]);
                % plot the picks
                plot3(app.UIAxes,T.X,T.Z,1e3*ones(size(T.X)),'ko-','MarkerFaceColor',[0.3 0.3 0.3])
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            colormap(app.UIAxes,value)
        end

        % Value changed function: SwitchSurfaceData
        function SwitchSurfaceDataValueChanged(app, event)
            value = app.SwitchSurfaceData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(false);
            end
        end

        % Value changed function: SwitchPointData
        function SwitchPointDataValueChanged(app, event)
            value = app.SwitchPointData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(false);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GMGDataPickerUIFigure = uifigure('Visible', 'off');
            app.GMGDataPickerUIFigure.Name = 'GMGDataPicker';
            app.UIAxes = uiaxes(app.GMGDataPickerUIFigure);
            xlabel(app.UIAxes, 'x [km]')
            ylabel(app.UIAxes, 'depth [km]')
            zlabel(app.UIAxes, 'Z')
            app.GeophysicalModelGeneratorDataPickerLabel = uilabel(app.GMGDataPickerUIFigure);
            app.GeophysicalModelGeneratorDataPickerLabel.Text = 'GeophysicalModelGenerator Data Picker';
            app.StartPickingButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.StartPickingButton.ButtonPushedFcn = createCallbackFcn(app, @StartPickingButtonPushed, true);
            app.StartPickingButton.Text = 'Start Picking';
            app.LoadPicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.LoadPicksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPicksButtonPushed, true);
            app.LoadPicksButton.Text = 'Load Picks';
            app.SavePicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.SavePicksButton.ButtonPushedFcn = createCallbackFcn(app, @SavePicksButtonPushed, true);
            app.SavePicksButton.Text = 'SavePicks';
            app.LoadDataButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @OpendatasetMenuSelected, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StopPickingButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.StopPickingButton.ButtonPushedFcn = createCallbackFcn(app, @StopPickingButtonPushed, true);
            app.StopPickingButton.Text = 'Stop Picking';
            app.ClearDataButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.VolumeDataDropDown = uidropdown(app.GMGDataPickerUIFigure);
            app.VolumeDataDropDown.ValueChangedFcn = createCallbackFcn(app, @VolumeDataDropDownValueChanged, true);
            app.SwitchVolumeData = uiswitch(app.GMGDataPickerUIFigure, 'slider');
            app.SwitchVolumeData.ValueChangedFcn = createCallbackFcn(app, @SwitchVolumeDataValueChanged, true);
            app.VolumedataLabel = uilabel(app.GMGDataPickerUIFigure);
            app.VolumedataLabel.Text = 'Volume data';
            app.SwitchSurfaceData = uiswitch(app.GMGDataPickerUIFigure, 'slider');
            app.SwitchSurfaceData.ValueChangedFcn = createCallbackFcn(app, @SwitchSurfaceDataValueChanged, true);
            app.SurfacedataLabel = uilabel(app.GMGDataPickerUIFigure);
            app.SurfacedataLabel.Text = 'Surface data';
            app.SwitchPointData = uiswitch(app.GMGDataPickerUIFigure, 'slider');
            app.SwitchPointData.ValueChangedFcn = createCallbackFcn(app, @SwitchPointDataValueChanged, true);
            app.PointdataLabel = uilabel(app.GMGDataPickerUIFigure);
            app.PointdataLabel.Text = 'Point data';
            app.ColormapDropDownLabel = uilabel(app.GMGDataPickerUIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GMGDataPickerUIFigure);
            app.ColormapDropDown.Items = {'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'turbo';
            app.CMinEditFieldLabel = uilabel(app.GMGDataPickerUIFigure);
            app.CMinEditFieldLabel.Text = 'CMin';
            app.CMinEditField = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @CMinEditFieldValueChanged, true);
            app.CMaxEditFieldLabel = uilabel(app.GMGDataPickerUIFigure);
            app.CMaxEditFieldLabel.Text = 'CMax';
            app.CMaxEditField = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 1;
            app.SetLimitsButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.SetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonPushed, true);
            app.SetLimitsButton.Text = 'Set Limits';
            app.YMax = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.YMin = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.XMax = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.XMin = uieditfield(app.GMGDataPickerUIFigure, 'numeric');
            app.ClearPicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.ClearPicksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPicksButtonPushed, true);
            app.ClearPicksButton.Text = 'Clear Picks';
            % Show the figure after all components are created
            app.GMGDataPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMGDataPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMGDataPickerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMGDataPickerUIFigure)
        end
    end
end